classdef MainGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        KeluarButton   matlab.ui.control.Button
        text5          matlab.ui.control.Label
        text4          matlab.ui.control.Label
        text3          matlab.ui.control.Label
        togglebutton2  matlab.ui.control.StateButton
        text2          matlab.ui.control.Label
        uitable2       matlab.ui.control.Table
        togglebutton1  matlab.ui.control.StateButton
        uitable1       matlab.ui.control.Table
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [1 1 1];
            app.uitable1.ColumnFormat = {[] [] 'shortG' [] [] []};
            app.uitable2.BackgroundColor = [1 1 1];
            app.uitable2.ColumnFormat = {[] 'longG' []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MainGUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % Choose default command line output for MainGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % center GUI
            movegui(gcf,'center');
            
            % UIWAIT makes MainGUI wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            %% Tampilkan dalam uitable
                % ambil data nama negara (kolom ke-1)
                dataNamaNegara = readtable('WHO COVID-19 global table data June 22nd 2021 at 10.52.14 PM.csv','Range','A3:A239');
            
                % ambil data kolom ke-3
                dataTotalKasus = readtable('WHO COVID-19 global table data June 22nd 2021 at 10.52.14 PM.csv','Range','C3:C239');
            
                % ambil data kolom ke-4
                dataTotalKasusPerPopulation = readtable('WHO COVID-19 global table data June 22nd 2021 at 10.52.14 PM.csv','Range','D3:D239');
            
                % read table kolom 5-7
                data = readtable('WHO COVID-19 global table data June 22nd 2021 at 10.52.14 PM.csv','Range','E3:G239');
            
                % ubah ke bentuk array/matrix
                dataNamaNegara = table2cell(dataNamaNegara);
                dataTotalKasus = table2cell(dataTotalKasus);
                dataTotalKasusPerPopulation = table2cell(dataTotalKasusPerPopulation);
                data = table2cell(data);
            
                % merge tabel
                data=[dataNamaNegara,dataTotalKasus,dataTotalKasusPerPopulation,data];
            
                % tampilkan pada uitable
                set(handles.uitable1,'data',data);
        end

        % Value changed function: togglebutton1
        function togglebutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton1pilih=1;
            set(handles.uitable2,'data',MainFunction(1));
        end

        % Value changed function: togglebutton2
        function togglebutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton2
            set(handles.uitable2,'data',MainFunction(2));
        end

        % Button pushed function: KeluarButton
        function KeluarButtonPushed(app, event)
            close(app.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MainGUI';
            app.figure1.Tag = 'figure1';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'Nama Negara'; 'Total Kasus'; 'Total Kasus / 100 Ribu Penduduk'; 'Total Kasus dalam 7 Hari Terakhir'; 'Total Kasus dalam 7 Hari Terakhir / 100 Ribu Penduduk'; 'Total Kasus dalam 24 Jam Terakhir'};
            app.uitable1.ColumnEditable = [false false false false false false];
            app.uitable1.Tag = 'uitable1';
            app.togglebutton1 = uibutton(app.figure1, 'state');
            app.togglebutton1.ValueChangedFcn = createCallbackFcn(app, @togglebutton1_Callback, true);
            app.togglebutton1.Tag = 'togglebutton1';
            app.togglebutton1.Text = 'Tingkat Berbahaya';
            app.uitable2 = uitable(app.figure1);
            app.uitable2.ColumnName = {'Nama Negara'; 'Nilai'; 'Hasil'};
            app.uitable2.ColumnEditable = [false false false];
            app.uitable2.Tag = 'uitable2';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Urutkan Berdasarkan';
            app.togglebutton2 = uibutton(app.figure1, 'state');
            app.togglebutton2.ValueChangedFcn = createCallbackFcn(app, @togglebutton2_Callback, true);
            app.togglebutton2.Tag = 'togglebutton2';
            app.togglebutton2.Text = 'Nama Negara';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Tabel Data';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tabel Hasil Perhitungan';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Sistem Menghitung Tingkat Riskan Negara '; 'Berdasarkan Data Covid19 (22 Juni 2021) dengan Metode Fuzzy AHP'};
            app.KeluarButton = uibutton(app.figure1, 'push');
            app.KeluarButton.ButtonPushedFcn = createCallbackFcn(app, @KeluarButtonPushed, true);
            app.KeluarButton.Text = 'Keluar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MainGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end